classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        Panel                           matlab.ui.container.Panel
        BtnFullStop                     matlab.ui.control.StateButton
        BtnBwdLeft                      matlab.ui.control.StateButton
        BtnFwdLeft                      matlab.ui.control.StateButton
        BtnBwdRight                     matlab.ui.control.StateButton
        BtnFwdRight                     matlab.ui.control.StateButton
        BtnLeft                         matlab.ui.control.StateButton
        BtnBackward                     matlab.ui.control.StateButton
        BtnRight                        matlab.ui.control.StateButton
        BtnForward                      matlab.ui.control.StateButton
        BtnCW                           matlab.ui.control.StateButton
        BtnCCW                          matlab.ui.control.StateButton
        AutonomousexplorationmodePanel  matlab.ui.container.Panel
        ExplorationModeSelectorKnob     matlab.ui.control.DiscreteKnob
        SimulationStatusLamp            matlab.ui.control.Lamp
        ConnectionLabel_2               matlab.ui.control.Label
        ConnStatusLamp                  matlab.ui.control.Lamp
        ConnectionLabel                 matlab.ui.control.Label
        BtnCamera                       matlab.ui.control.Button
        SldSpeed                        matlab.ui.control.Slider
        SPEEDLabel                      matlab.ui.control.Label
        BtnStream                       matlab.ui.control.Button
        BtnSimulate                     matlab.ui.control.Button
        BtnConnect                      matlab.ui.control.Button
        UIAxes                          matlab.ui.control.UIAxes
    end


    % Public properties that correspond to the Simulink model
    properties (Access = public, Transient)
        Simulation simulink.Simulation
    end

    properties (Access = private)
        connection;                       % Instance of RobotConnection
        baseControl;                      % Base control object
        visionSensor;                     % Vision Sensor object
        wheelsSpeed = 1.0;                % Wheel's speed
        statusOffColor = [0.5, 0.5, 0.5]; % Gray color for the "off" status lamp 
        statusOnColor = 'green';          % Green color for the "on" status lamp 
        autoExplorer;                     % Instance of the autoExplorer
    end
    
    methods (Access = private)
        
        function retrieveAndInitializeWheels(app)
                        
            % Retrieve wheel handles from CoppeliaSim (use correct object names)
            [handleWheelFR, ~] = app.connection.getHandle('rollingJoint_fr');
            [handleWheelFL, ~] = app.connection.getHandle('rollingJoint_fl');
            [handleWheelRR, ~] = app.connection.getHandle('rollingJoint_rr');
            [handleWheelRL, ~] = app.connection.getHandle('rollingJoint_rl');

            % Store the handles in an array to pass to BaseControl
            wheelHandles = [handleWheelFR, handleWheelFL, handleWheelRR, handleWheelRL];

            % Initialize the BaseControl object
            app.baseControl = BaseControl(wheelHandles);

            % Initialize the AutoExplorer object
            [hokuyoSensor, ~] = app.connection.getHandle('youBot/Hokuyo');
            app.autoExplorer = AutonomousExplorer(app.baseControl,app.connection.sim, app.connection.clientID, hokuyoSensor, 0.01);
           
            % Initialize the VisionSensor
             [visionHandle, ~] = app.connection.getHandle('/youBot/rgb');
             app.visionSensor = VisionSensor(visionHandle);
        end

        function toggleControls(app,state)
            app.toggleSimulationButton(state);
            app.toggleCameraButtons(state);
            app.toggleMovementButtons(state);
        end

        function toggleConnectButton(app)
            if app.connection.clientID == -1
                app.BtnConnect.Text = 'Connect';
                app.BtnConnect.Icon = 'icons/Connect.png';
                app.ConnStatusLamp.Color = app.statusOffColor;
            else
                app.BtnConnect.Text = 'Disconnect';
                app.BtnConnect.Icon = 'icons/Disconnect.png';
                app.ConnStatusLamp.Color = app.statusOnColor;
            end
        end

        function toggleSimulationButton(app,state)
            if app.connection.clientID > -1
                app.BtnSimulate.Enable = "on";
            else 
                app.BtnSimulate.Enable = state;
            end

            if app.connection.isSimulationRunning
                app.SimulationStatusLamp.Color = app.statusOnColor;
                app.BtnSimulate.Icon = 'icons/Stop.png';
            else
                app.SimulationStatusLamp.Color = app.statusOffColor;
                app.BtnSimulate.Icon = 'icons/Start.png';
            end
       end

        function toggleCameraButtons(app,state)
            app.BtnStream.Enable = state;
            app.BtnCamera.Enable = state;
        end

        function toggleMovementButtons(app,state)
            
            app.SldSpeed.Enable = state;

            movementButtons = {app.BtnForward, app.BtnBackward, app.BtnLeft, app.BtnRight, ...
                               app.BtnCW, app.BtnCCW, app.BtnFwdLeft, app.BtnFwdRight, ...
                               app.BtnBwdLeft, app.BtnBwdRight, app.BtnFullStop};

            % Loop through all movement buttons and set their status
            for i = 1:length(movementButtons)
                movementButtons{i}.Enable = state;
            end
        end

        function toggleAutoExploreButton(app,state)
            app.ExplorationModeSelectorKnob.Enable = state;
        end

        function buttonsInitialSetup(app)

            app.toggleConnectButton();
            app.toggleSimulationButton('off');
            app.toggleCameraButtons('off');
            app.toggleMovementButtons('off');
            app.toggleAutoExploreButton('off');

            %assign unique identifiers to the movement buttons so we can
            %have a single generic method to move the robot

            app.BtnForward.Tag = 'FORWARD';
            app.BtnBackward.Tag = 'BACKWARD';
            app.BtnLeft.Tag = 'LEFT';
            app.BtnRight.Tag = 'RIGHT';
            app.BtnCW.Tag = 'CLOCKWISE';
            app.BtnCCW.Tag = 'COUNTER_CLOCKWISE';
            app.BtnFwdLeft.Tag = 'DIAG_FL';
            app.BtnFwdRight.Tag = 'DIAG_FR';
            app.BtnBwdLeft.Tag = 'DIAG_BL';
            app.BtnBwdRight.Tag = 'DIAG_BR';
            app.BtnFullStop.Tag = 'FULL_STOP';

            app.SldSpeed.Limits = [1,100];
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Initialize RobotConnection
            app.connection = RobotConnection();
            app.buttonsInitialSetup();
        end

        % Button pushed function: BtnConnect
        function BtnConnectButtonPushed(app, event)
            % Check the current state of the connection
            if app.connection.clientID == -1
                % Attempt to connect
                success = app.connection.connect();
                if success
                    app.retrieveAndInitializeWheels();
                    
                    % Change the connect button to disconnect
                    app.toggleConnectButton();

                    % Enable Simulation controls
                    app.toggleSimulationButton('on');
                else
                    %failed to connect to Coppelia
                     uialert(app.UIFigure, 'Connection failed.Something went wrong, please check the logs', 'Status');
                end

            else % If already connected, disconnect

                %check if it's running and stop the simulation 
                if app.connection.isSimulationRunning
                    app.connection.stopSimulation();
                    app.toggleAutoExploreButton('off');
                    app.autoExplorer.isWandering = false;
                end

                app.connection.disconnect();

                % Change back the connect button to connect
                app.toggleConnectButton();


                % Disable UI controls 
                app.toggleControls("off");
            end
        end

        % Button pushed function: BtnSimulate
        function BtnSimulateButtonPushed(app, event)
            % Check if the client is connected
            if app.connection.clientID > -1

                if ~app.connection.isSimulationRunning
                    app.connection.startSimulation();

                    if app.connection.isSimulationRunning
                        % Enable UI controls
                        app.toggleControls('on');
                        app.toggleAutoExploreButton('on');
                    end

                else
                    % Stop the simulation
                    app.connection.stopSimulation();
                    
                    % Check if the simulation stopped successfully
                    if ~app.connection.isSimulationRunning
                        % Disable UI controls
                        app.toggleControls('off');
                        app.toggleAutoExploreButton('off');
                        app.autoExplorer.isWandering = false;
                    end
                end
            else
                disp('Please connect to CoppeliaSim before starting the simulation.');
            end
        end

        % Value changed function: SldSpeed
        function SldSpeedValueChanged(app, event)
            newSpeedValue  = app.SldSpeed.Value;
            app.wheelsSpeed = (newSpeedValue * 5.0) / 100;
        end

        % Button pushed function: BtnCamera
        function BtnCameraButtonPushed(app, event)
            app.visionSensor.captureAndDisplayImage(app.connection.sim, app.connection.clientID, app);
        end

        % Button pushed function: BtnStream
        function BtnStreamButtonPushed(app, event)
            % Toggle streaming state
            app.visionSensor.isStreaming = ~app.visionSensor.isStreaming;
            
            % Start streaming
            while app.visionSensor.isStreaming && app.connection.clientID > -1 && app.connection.isSimulationRunning
                app.visionSensor.captureAndDisplayImage(app.connection.sim, app.connection.clientID, app);
                pause(0.1);  % Control the frame rate of the stream
            end


        end

        % Value changed function: BtnBackward, BtnBwdLeft, BtnBwdRight, 
        % ...and 8 other components
        function BtnMovementValueChanged(app, event)
            value = event.Source.Value;
            movementType = event.Source.Tag;  % Get the movement type from the button's tag

            % if the Full stop button is clicked
            if strcmp(movementType, 'FULL_STOP')
                % Get a list of all movement buttons
                movementButtons = {app.BtnForward, app.BtnBackward, app.BtnLeft,...
                                   app.BtnRight,app.BtnCW, app.BtnCCW, app.BtnFwdLeft,...
                                   app.BtnFwdRight,app.BtnBwdLeft, app.BtnBwdRight, ...
                                   app.BtnFullStop};
    
                % Stop the robot
                app.baseControl.stop(app.connection.sim, app.connection.clientID);

                % Loop through all movement buttons and set their value to 0
                for i = 1:length(movementButtons)
                    movementButtons{i}.Value = 0;
                end
            else
               % For other movement buttons, move the robot if pressed
                if value == 1  % Button is pressed
                    app.baseControl.move(app.connection.sim, app.connection.clientID,app.baseControl.WheelMovements.(movementType), app.wheelsSpeed);
                else
                    app.baseControl.stop(app.connection.sim, app.connection.clientID);
                end
            end
        end

        % Value changed function: ExplorationModeSelectorKnob
        function ExplorationModeSelectorKnobValueChanged(app, event)
            value = app.ExplorationModeSelectorKnob.Value;
            if strcmpi(value,"on")
                app.toggleMovementButtons("off");
                app.autoExplorer.isWandering = true;
            else
                app.autoExplorer.isWandering = false;
                app.toggleMovementButtons("on");
                app.baseControl.stop(app.connection.sim, app.connection.clientID);
            end

             while app.autoExplorer.isWandering
                 app.autoExplorer.wanderAndAvoid(app.wheelsSpeed);
                 pause(0.1);  % Control the frame rate of the stream
             end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Sensor')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.BtnConnect = uibutton(app.UIFigure, 'push');
            app.BtnConnect.ButtonPushedFcn = createCallbackFcn(app, @BtnConnectButtonPushed, true);
            app.BtnConnect.Icon = fullfile(pathToMLAPP, 'icons', 'Connect.png');
            app.BtnConnect.Text = '';
            app.BtnSimulate = uibutton(app.UIFigure, 'push');
            app.BtnSimulate.ButtonPushedFcn = createCallbackFcn(app, @BtnSimulateButtonPushed, true);
            app.BtnSimulate.Icon = fullfile(pathToMLAPP, 'icons', 'Start.png');
            app.BtnSimulate.Enable = 'off';
            app.BtnSimulate.Text = '';
            app.BtnStream = uibutton(app.UIFigure, 'push');
            app.BtnStream.ButtonPushedFcn = createCallbackFcn(app, @BtnStreamButtonPushed, true);
            app.BtnStream.Icon = fullfile(pathToMLAPP, 'icons', 'streaming.png');
            app.BtnStream.Enable = 'off';
            app.BtnStream.Text = '';
            app.SPEEDLabel = uilabel(app.UIFigure);
            app.SPEEDLabel.Enable = 'off';
            app.SPEEDLabel.Text = 'SPEED';
            app.SldSpeed = uislider(app.UIFigure);
            app.SldSpeed.ValueChangedFcn = createCallbackFcn(app, @SldSpeedValueChanged, true);
            app.SldSpeed.Enable = 'off';
            app.BtnCamera = uibutton(app.UIFigure, 'push');
            app.BtnCamera.ButtonPushedFcn = createCallbackFcn(app, @BtnCameraButtonPushed, true);
            app.BtnCamera.Icon = fullfile(pathToMLAPP, 'icons', 'camera.png');
            app.BtnCamera.Enable = 'off';
            app.BtnCamera.Text = '';
            app.ConnectionLabel = uilabel(app.UIFigure);
            app.ConnectionLabel.Text = 'Connection';
            app.ConnStatusLamp = uilamp(app.UIFigure);
            app.ConnectionLabel_2 = uilabel(app.UIFigure);
            app.ConnectionLabel_2.Text = 'Simulation';
            app.SimulationStatusLamp = uilamp(app.UIFigure);
            app.AutonomousexplorationmodePanel = uipanel(app.UIFigure);
            app.AutonomousexplorationmodePanel.Title = 'Autonomous exploration mode';
            app.ExplorationModeSelectorKnob = uiknob(app.AutonomousexplorationmodePanel, 'discrete');
            app.ExplorationModeSelectorKnob.Items = {'On', 'Off'};
            app.ExplorationModeSelectorKnob.ValueChangedFcn = createCallbackFcn(app, @ExplorationModeSelectorKnobValueChanged, true);
            app.Panel = uipanel(app.UIFigure);
            app.BtnCCW = uibutton(app.Panel, 'state');
            app.BtnCCW.ValueChangedFcn = createCallbackFcn(app, @BtnMovementValueChanged, true);
            app.BtnCCW.Enable = 'off';
            app.BtnCCW.Icon = fullfile(pathToMLAPP, 'icons', 'CCW.png');
            app.BtnCCW.Text = '';
            app.BtnCW = uibutton(app.Panel, 'state');
            app.BtnCW.ValueChangedFcn = createCallbackFcn(app, @BtnMovementValueChanged, true);
            app.BtnCW.Enable = 'off';
            app.BtnCW.Icon = fullfile(pathToMLAPP, 'icons', 'CW.png');
            app.BtnCW.Text = '';
            app.BtnForward = uibutton(app.Panel, 'state');
            app.BtnForward.ValueChangedFcn = createCallbackFcn(app, @BtnMovementValueChanged, true);
            app.BtnForward.Enable = 'off';
            app.BtnForward.Icon = fullfile(pathToMLAPP, 'icons', 'ArrowUp.png');
            app.BtnForward.Text = '';
            app.BtnRight = uibutton(app.Panel, 'state');
            app.BtnRight.ValueChangedFcn = createCallbackFcn(app, @BtnMovementValueChanged, true);
            app.BtnRight.Enable = 'off';
            app.BtnRight.Icon = fullfile(pathToMLAPP, 'icons', 'ArrowRight.png');
            app.BtnRight.Text = '';
            app.BtnBackward = uibutton(app.Panel, 'state');
            app.BtnBackward.ValueChangedFcn = createCallbackFcn(app, @BtnMovementValueChanged, true);
            app.BtnBackward.Enable = 'off';
            app.BtnBackward.Icon = fullfile(pathToMLAPP, 'icons', 'ArrowDown.png');
            app.BtnBackward.Text = '';
            app.BtnLeft = uibutton(app.Panel, 'state');
            app.BtnLeft.ValueChangedFcn = createCallbackFcn(app, @BtnMovementValueChanged, true);
            app.BtnLeft.Enable = 'off';
            app.BtnLeft.Icon = fullfile(pathToMLAPP, 'icons', 'ArrowLeft.png');
            app.BtnLeft.Text = '';
            app.BtnFwdRight = uibutton(app.Panel, 'state');
            app.BtnFwdRight.ValueChangedFcn = createCallbackFcn(app, @BtnMovementValueChanged, true);
            app.BtnFwdRight.Enable = 'off';
            app.BtnFwdRight.Icon = fullfile(pathToMLAPP, 'icons', 'FwdRight.png');
            app.BtnFwdRight.Text = '';
            app.BtnBwdRight = uibutton(app.Panel, 'state');
            app.BtnBwdRight.ValueChangedFcn = createCallbackFcn(app, @BtnMovementValueChanged, true);
            app.BtnBwdRight.Enable = 'off';
            app.BtnBwdRight.Icon = fullfile(pathToMLAPP, 'icons', 'BwdRight.png');
            app.BtnBwdRight.Text = '';
            app.BtnFwdLeft = uibutton(app.Panel, 'state');
            app.BtnFwdLeft.ValueChangedFcn = createCallbackFcn(app, @BtnMovementValueChanged, true);
            app.BtnFwdLeft.Enable = 'off';
            app.BtnFwdLeft.Icon = fullfile(pathToMLAPP, 'icons', 'FwdLeft.png');
            app.BtnFwdLeft.Text = '';
            app.BtnBwdLeft = uibutton(app.Panel, 'state');
            app.BtnBwdLeft.ValueChangedFcn = createCallbackFcn(app, @BtnMovementValueChanged, true);
            app.BtnBwdLeft.Enable = 'off';
            app.BtnBwdLeft.Icon = fullfile(pathToMLAPP, 'icons', 'BwdLeft.png');
            app.BtnBwdLeft.Text = '';
            app.BtnFullStop = uibutton(app.Panel, 'state');
            app.BtnFullStop.ValueChangedFcn = createCallbackFcn(app, @BtnMovementValueChanged, true);
            app.BtnFullStop.Enable = 'off';
            app.BtnFullStop.Icon = fullfile(pathToMLAPP, 'icons', 'Stop.png');
            app.BtnFullStop.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end